classdef Scint_City < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ScintCityUIFigure               matlab.ui.Figure
        RunScintCityButton              matlab.ui.control.Button
        dirnameEditFieldLabel           matlab.ui.control.Label
        dirnameEditField                matlab.ui.control.EditField
        SavefigsSwitchLabel             matlab.ui.control.Label
        SavefigsSwitch                  matlab.ui.control.Switch
        TabGroup                        matlab.ui.container.TabGroup
        OptimizationTab                 matlab.ui.container.Tab
        OptimizeSwitchLabel             matlab.ui.control.Label
        OptimizeSwitch                  matlab.ui.control.Switch
        NumberofOptimizationsEditField  matlab.ui.control.NumericEditField
        NumberofOptimizationsEditFieldLabel  matlab.ui.control.Label
        DEditFieldLabel                 matlab.ui.control.Label
        DEditField                      matlab.ui.control.NumericEditField
        ConstraintsDropDownLabel        matlab.ui.control.Label
        ConstraintsDropDown             matlab.ui.control.DropDown
        DirectionalitySwitchLabel       matlab.ui.control.Label
        DirectionalitySwitch            matlab.ui.control.Switch
        OptimizationMethodLabel         matlab.ui.control.Label
        LowerboundEditFieldLabel        matlab.ui.control.Label
        LowerboundEditField             matlab.ui.control.NumericEditField
        UpperboundEditFieldLabel        matlab.ui.control.Label
        UpperboundEditField             matlab.ui.control.NumericEditField
        KnownStructureTab               matlab.ui.container.Tab
        dEditField                      matlab.ui.control.EditField
        dEditFieldLabel                 matlab.ui.control.Label
        nEditFieldLabel                 matlab.ui.control.Label
        nEditField                      matlab.ui.control.EditField
        scintindexLabel                 matlab.ui.control.Label
        scintindexEditField             matlab.ui.control.EditField
        LoaddatafromdirectoryCheckBox   matlab.ui.control.CheckBox
        ImageProcessingTab              matlab.ui.container.Tab
        DistancebetweenDeviceandCameraEditFieldLabel  matlab.ui.control.Label
        DistancebetweenDeviceandCameraEditField  matlab.ui.control.NumericEditField
        NumberofBinsEditFieldLabel      matlab.ui.control.Label
        NumberofBinsEditField           matlab.ui.control.NumericEditField
        VarianceofOnionNoiseEditFieldLabel  matlab.ui.control.Label
        VarianceofOnionNoiseEditField   matlab.ui.control.NumericEditField
        ShowImageProcessingCheckBox     matlab.ui.control.CheckBox
        SimulationPanel                 matlab.ui.container.Panel
        RunSimulationSwitchLabel        matlab.ui.control.Label
        RunSimulationSwitch             matlab.ui.control.Switch
        LumericalpathEditFieldLabel     matlab.ui.control.Label
        LumericalpathEditField          matlab.ui.control.EditField
        TabGroup2                       matlab.ui.container.TabGroup
        StructureparametersTab          matlab.ui.container.Tab
        n_substrateEditField_2          matlab.ui.control.NumericEditField
        n_substrateEditField_2Label     matlab.ui.control.Label
        n_scintEditField_2              matlab.ui.control.NumericEditField
        n_scintEditField_2Label         matlab.ui.control.Label
        n_otherEditField_2              matlab.ui.control.NumericEditField
        n_otherEditField_2Label         matlab.ui.control.Label
        other_absorptionEditField       matlab.ui.control.NumericEditField
        other_absorptionEditFieldLabel  matlab.ui.control.Label
        scint_absorptionEditField       matlab.ui.control.NumericEditField
        scint_absorptionEditFieldLabel  matlab.ui.control.Label
        ExponentialabsorbtionSwitchLabel  matlab.ui.control.Label
        ExponentialabsorbtionSwitch     matlab.ui.control.Switch
        pairsEditFieldLabel             matlab.ui.control.Label
        pairsEditField                  matlab.ui.control.EditField
        PlotexponentialabsorbtionCheckBox  matlab.ui.control.CheckBox
        EmittersTab                     matlab.ui.container.Tab
        anglesEditFieldLabel            matlab.ui.control.Label
        anglesEditField                 matlab.ui.control.EditField
        wavelengthsEditField            matlab.ui.control.EditField
        wavelengthsEditFieldLabel       matlab.ui.control.Label
        SpectraldistEYEditField         matlab.ui.control.NumericEditField
        SpectraldistEYEditFieldLabel    matlab.ui.control.Label
        SpectraldistSTDYEditField       matlab.ui.control.NumericEditField
        SpectraldistSTDYEditFieldLabel  matlab.ui.control.Label
        ComputationmethodTab            matlab.ui.container.Tab
        GzSwitchLabel                   matlab.ui.control.Label
        GzSwitch                        matlab.ui.control.Switch
        NzdzEditFieldLabel              matlab.ui.control.Label
        NzdzEditField                   matlab.ui.control.NumericEditField
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunScintCityButton
        function RunScintCityButtonPushed(app, event)
            
            theta_vec = str2double(strsplit(app.anglesEditField.Value));
            if length(theta_vec) == 1
               theta = theta_vec(1); 
            else
               theta = linspace(theta_vec(1), theta_vec(2), theta_vec(3)); 
            end
            
            lambda_vec = str2double(strsplit(app.wavelengthsEditField.Value));
            if length(lambda_vec) == 1
               lambda = lambda_vec(1); 
            else
               lambda = linspace(lambda_vec(1), lambda_vec(2), lambda_vec(3)); 
            end
            
            pairs_vec = str2double(strsplit(app.pairsEditField.Value));
            if length(pairs_vec) == 1
               pairs = pairs_vec(1); 
            else
               pairs = linspace(pairs_vec(1), pairs_vec(2), pairs_vec(3)); 
            end
            
            if isempty(app.dEditField.Value)
                d = [];
            else
                d = str2double(strsplit(app.dEditField.Value));
            end
            
            if isempty(app.nEditField.Value)
                n = [];
            else
                n = str2double(strsplit(app.nEditField.Value));
            end
            
            if isempty(app.scintindexEditField.Value)
                i_scint = [];
            else
                i_scint = str2double(strsplit(app.scintindexEditField.Value));
            end
            
            if app.GzSwitch.Value == "dz"
                is_Gz = false;
                dz_Nz = app.NzdzEditField.Value;
            else
                is_Gz = true;
                dz_Nz = app.NzdzEditField.Value;
            end
            
            mu_lambda       = app.SpectraldistEYEditField.Value;
            sigma_lambda    = app.SpectraldistSTDYEditField.Value;
            n_si            = app.n_substrateEditField_2.Value;
            n_scint         = app.n_scintEditField_2.Value;
            n_ox            = app.n_otherEditField_2.Value;
            num_iterations  = app.NumberofOptimizationsEditField.Value;
            total_size      = app.DEditField.Value;
            lower_bound     = app.LowerboundEditField.Value;
            upper_bound     = app.UpperboundEditField.Value;
            constraint      = str2double(app.ConstraintsDropDown.Value(1));
            optimize        = app.OptimizeSwitch.Value ~= "Known Structure";
            optimization_method = app.DirectionalitySwitch.Value;
            save_fig        = app.SavefigsSwitch.Value == "On";
            dir_name        = app.dirnameEditField.Value;
            
            control.is_Gz = is_Gz; control.dz_Nz = dz_Nz;
            control.is_exponential_absorption = app.ExponentialabsorbtionSwitch.Value ~= "Uniform dist.";
            control.absorption_scint = app.scint_absorptionEditField.Value;
            control.absorption_other = app.other_absorptionEditField.Value;
            control.plot_exponential_absorption = app.PlotexponentialabsorbtionCheckBox.Value;
            control.load_data_from_dir = app.LoaddatafromdirectoryCheckBox.Value;
            
            ImageProcessing_Params.nbins = app.NumberofBinsEditField.Value;
            ImageProcessing_Params.h     = app.DistancebetweenDeviceandCameraEditField.Value;
            ImageProcessing_Params.sigma_onion_noise = app.VarianceofOnionNoiseEditField.Value;
            ImageProcessing_Params.Show_Image_Processing = app.ShowImageProcessingCheckBox.Value;
            
            simulation_params.simulate = app.RunSimulationSwitch.Value == "On";
            simulation_params.lumerical_path = app.LumericalpathEditField.Value;
            
            progress_bar = uiprogressdlg(uifigure,'Title','Computing Scint City','Indeterminate','on');

            set(groot, 'defaultFigurePosition', [100 100 900 600]); % figure size
            set(groot, 'defaultTextInterpreter', 'latex'); % latex
            set(groot, 'defaultLegendInterpreter', 'latex'); % latex
            set(groot, 'defaultAxesTickLabelInterpreter','latex'); % latex
            set(0, 'DefaultLineLineWidth', 4);
            
            One_D_PhC_Optimization(                 ...
                                    theta,          ...
                                    lambda,         ...
                                    mu_lambda,      ...
                                    sigma_lambda,   ...
                                    pairs,          ...
                                    n_si,           ...
                                    n_scint,        ...
                                    n_ox,           ...
                                    d,              ...
                                    n,              ...
                                    i_scint,        ...
                                    control,        ...
                                    num_iterations, ...
                                    total_size,     ...
                                    lower_bound,    ...
                                    upper_bound,    ...
                                    constraint,     ...
                                    optimize,       ...
                                    optimization_method,       ...
                                    ImageProcessing_Params, ...
                                    save_fig,       ...
                                    dir_name,       ...
                                    simulation_params);
                                
            % close the dialog box
            close(progress_bar);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ScintCityUIFigure = uifigure('Visible', 'off');
            app.ScintCityUIFigure.Name = 'Scint City';
            app.RunScintCityButton = uibutton(app.ScintCityUIFigure, 'push');
            app.RunScintCityButton.ButtonPushedFcn = createCallbackFcn(app, @RunScintCityButtonPushed, true);
            app.RunScintCityButton.Text = 'Run Scint-City';
            app.dirnameEditFieldLabel = uilabel(app.ScintCityUIFigure);
            app.dirnameEditFieldLabel.Text = 'dir name';
            app.dirnameEditField = uieditfield(app.ScintCityUIFigure, 'text');
            app.dirnameEditField.Value = 'Results';
            app.SavefigsSwitchLabel = uilabel(app.ScintCityUIFigure);
            app.SavefigsSwitchLabel.Text = 'Save figs';
            app.SavefigsSwitch = uiswitch(app.ScintCityUIFigure, 'slider');
            app.TabGroup = uitabgroup(app.ScintCityUIFigure);
            app.OptimizationTab = uitab(app.TabGroup);
            app.OptimizationTab.Title = 'Optimization';
            app.OptimizeSwitchLabel = uilabel(app.OptimizationTab);
            app.OptimizeSwitchLabel.Text = 'Optimize';
            app.OptimizeSwitch = uiswitch(app.OptimizationTab, 'slider');
            app.OptimizeSwitch.Items = {'', 'Known Structure'};
            app.OptimizeSwitch.Value = '';
            app.NumberofOptimizationsEditField = uieditfield(app.OptimizationTab, 'numeric');
            app.NumberofOptimizationsEditField.Value = 10;
            app.NumberofOptimizationsEditFieldLabel = uilabel(app.OptimizationTab);
            app.NumberofOptimizationsEditFieldLabel.Text = 'Number of Optimizations';
            app.DEditFieldLabel = uilabel(app.OptimizationTab);
            app.DEditFieldLabel.Text = 'D';
            app.DEditField = uieditfield(app.OptimizationTab, 'numeric');
            app.DEditField.Value = 1000;
            app.ConstraintsDropDownLabel = uilabel(app.OptimizationTab);
            app.ConstraintsDropDownLabel.Text = 'Constraints';
            app.ConstraintsDropDown = uidropdown(app.OptimizationTab);
            app.ConstraintsDropDown.Items = {'1: Dscint = D and Dother = D', '2: Each layer is <= D', '3: Dscint + Dother = D', '4: Dscint = D and Dother <= D'};
            app.ConstraintsDropDown.Value = '1: Dscint = D and Dother = D';
            app.DirectionalitySwitchLabel = uilabel(app.OptimizationTab);
            app.DirectionalitySwitchLabel.Text = 'Directionality';
            app.DirectionalitySwitch = uiswitch(app.OptimizationTab, 'slider');
            app.DirectionalitySwitch.Items = {'', 'Efficiency'};
            app.DirectionalitySwitch.Value = '';
            app.OptimizationMethodLabel = uilabel(app.OptimizationTab);
            app.OptimizationMethodLabel.Text = 'Optimization Method:';
            app.LowerboundEditFieldLabel = uilabel(app.OptimizationTab);
            app.LowerboundEditFieldLabel.Text = 'Lower bound';
            app.LowerboundEditField = uieditfield(app.OptimizationTab, 'numeric');
            app.LowerboundEditField.Value = 20;
            app.UpperboundEditFieldLabel = uilabel(app.OptimizationTab);
            app.UpperboundEditFieldLabel.Text = 'Upper bound';
            app.UpperboundEditField = uieditfield(app.OptimizationTab, 'numeric');
            app.UpperboundEditField.Value = 1000;
            app.KnownStructureTab = uitab(app.TabGroup);
            app.KnownStructureTab.Title = 'Known Structure';
            app.dEditField = uieditfield(app.KnownStructureTab, 'text');
            app.dEditFieldLabel = uilabel(app.KnownStructureTab);
            app.dEditFieldLabel.Text = 'd';
            app.nEditFieldLabel = uilabel(app.KnownStructureTab);
            app.nEditFieldLabel.Text = 'n';
            app.nEditField = uieditfield(app.KnownStructureTab, 'text');
            app.scintindexLabel = uilabel(app.KnownStructureTab);
            app.scintindexLabel.Text = 'scint index';
            app.scintindexEditField = uieditfield(app.KnownStructureTab, 'text');
            app.LoaddatafromdirectoryCheckBox = uicheckbox(app.KnownStructureTab);
            app.LoaddatafromdirectoryCheckBox.Text = 'Load data from directory';
            app.ImageProcessingTab = uitab(app.TabGroup);
            app.ImageProcessingTab.Title = 'Image Processing';
            app.DistancebetweenDeviceandCameraEditFieldLabel = uilabel(app.ImageProcessingTab);
            app.DistancebetweenDeviceandCameraEditFieldLabel.Text = 'Distance between Device and Camera';
            app.DistancebetweenDeviceandCameraEditField = uieditfield(app.ImageProcessingTab, 'numeric');
            app.NumberofBinsEditFieldLabel = uilabel(app.ImageProcessingTab);
            app.NumberofBinsEditFieldLabel.Text = 'Number of Bins';
            app.NumberofBinsEditField = uieditfield(app.ImageProcessingTab, 'numeric');
            app.NumberofBinsEditField.Value = 40;
            app.VarianceofOnionNoiseEditFieldLabel = uilabel(app.ImageProcessingTab);
            app.VarianceofOnionNoiseEditFieldLabel.Text = 'Variance of Onion Noise';
            app.VarianceofOnionNoiseEditField = uieditfield(app.ImageProcessingTab, 'numeric');
            app.VarianceofOnionNoiseEditField.Value = 1;
            app.ShowImageProcessingCheckBox = uicheckbox(app.ImageProcessingTab);
            app.ShowImageProcessingCheckBox.Text = 'Show Image Processing';
            app.SimulationPanel = uipanel(app.ScintCityUIFigure);
            app.SimulationPanel.Title = 'Simulation';
            app.RunSimulationSwitchLabel = uilabel(app.SimulationPanel);
            app.RunSimulationSwitchLabel.Text = 'Run Simulation';
            app.RunSimulationSwitch = uiswitch(app.SimulationPanel, 'slider');
            app.LumericalpathEditFieldLabel = uilabel(app.SimulationPanel);
            app.LumericalpathEditFieldLabel.Text = 'Lumerical path';
            app.LumericalpathEditField = uieditfield(app.SimulationPanel, 'text');
            app.LumericalpathEditField.Value = 'C:\Program Files\Lumerical\FDTD\bin\fdtd-solutions';
            app.TabGroup2 = uitabgroup(app.ScintCityUIFigure);
            app.StructureparametersTab = uitab(app.TabGroup2);
            app.StructureparametersTab.Title = 'Structure parameters';
            app.n_substrateEditField_2 = uieditfield(app.StructureparametersTab, 'numeric');
            app.n_substrateEditField_2.Value = 4.3;
            app.n_substrateEditField_2Label = uilabel(app.StructureparametersTab);
            app.n_substrateEditField_2Label.Text = 'n_substrate';
            app.n_scintEditField_2 = uieditfield(app.StructureparametersTab, 'numeric');
            app.n_scintEditField_2.Value = 1.6;
            app.n_scintEditField_2Label = uilabel(app.StructureparametersTab);
            app.n_scintEditField_2Label.Text = 'n_scint';
            app.n_otherEditField_2 = uieditfield(app.StructureparametersTab, 'numeric');
            app.n_otherEditField_2.Value = 2.1;
            app.n_otherEditField_2Label = uilabel(app.StructureparametersTab);
            app.n_otherEditField_2Label.Text = 'n_other';
            app.other_absorptionEditField = uieditfield(app.StructureparametersTab, 'numeric');
            app.other_absorptionEditField.Value = 100000;
            app.other_absorptionEditFieldLabel = uilabel(app.StructureparametersTab);
            app.other_absorptionEditFieldLabel.Text = 'other_absorption';
            app.scint_absorptionEditField = uieditfield(app.StructureparametersTab, 'numeric');
            app.scint_absorptionEditField.Value = 100000;
            app.scint_absorptionEditFieldLabel = uilabel(app.StructureparametersTab);
            app.scint_absorptionEditFieldLabel.Text = 'scint_absorption';
            app.ExponentialabsorbtionSwitchLabel = uilabel(app.StructureparametersTab);
            app.ExponentialabsorbtionSwitchLabel.Text = 'Exponential absorbtion';
            app.ExponentialabsorbtionSwitch = uiswitch(app.StructureparametersTab, 'slider');
            app.ExponentialabsorbtionSwitch.Items = {'', 'Uniform dist.'};
            app.ExponentialabsorbtionSwitch.Value = '';
            app.pairsEditFieldLabel = uilabel(app.StructureparametersTab);
            app.pairsEditFieldLabel.Text = 'pairs';
            app.pairsEditField = uieditfield(app.StructureparametersTab, 'text');
            app.pairsEditField.Value = '10';
            app.PlotexponentialabsorbtionCheckBox = uicheckbox(app.StructureparametersTab);
            app.PlotexponentialabsorbtionCheckBox.Text = 'Plot exponential absorbtion';
            app.EmittersTab = uitab(app.TabGroup2);
            app.EmittersTab.Title = 'Emitters';
            app.anglesEditFieldLabel = uilabel(app.EmittersTab);
            app.anglesEditFieldLabel.Text = 'angles';
            app.anglesEditField = uieditfield(app.EmittersTab, 'text');
            app.anglesEditField.Value = '0';
            app.wavelengthsEditField = uieditfield(app.EmittersTab, 'text');
            app.wavelengthsEditField.Value = '410 450 301';
            app.wavelengthsEditFieldLabel = uilabel(app.EmittersTab);
            app.wavelengthsEditFieldLabel.Text = 'wavelengths';
            app.SpectraldistEYEditField = uieditfield(app.EmittersTab, 'numeric');
            app.SpectraldistEYEditField.Value = 430;
            app.SpectraldistEYEditFieldLabel = uilabel(app.EmittersTab);
            app.SpectraldistEYEditFieldLabel.Text = 'Spectral dist. - E(Y)';
            app.SpectraldistSTDYEditField = uieditfield(app.EmittersTab, 'numeric');
            app.SpectraldistSTDYEditField.Value = 4;
            app.SpectraldistSTDYEditFieldLabel = uilabel(app.EmittersTab);
            app.SpectraldistSTDYEditFieldLabel.Text = 'Spectral dist. - STD(Y)';
            app.ComputationmethodTab = uitab(app.TabGroup2);
            app.ComputationmethodTab.Title = 'Computation method';
            app.GzSwitchLabel = uilabel(app.ComputationmethodTab);
            app.GzSwitchLabel.Text = 'G(z)';
            app.GzSwitch = uiswitch(app.ComputationmethodTab, 'slider');
            app.GzSwitch.Items = {'', 'dz'};
            app.GzSwitch.Value = '';
            app.NzdzEditFieldLabel = uilabel(app.ComputationmethodTab);
            app.NzdzEditFieldLabel.Text = 'Nz/dz';
            app.NzdzEditField = uieditfield(app.ComputationmethodTab, 'numeric');
            app.NzdzEditField.Value = 10;
            % Show the figure after all components are created
            app.ScintCityUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Scint_City

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ScintCityUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ScintCityUIFigure)
        end
    end
end